classdef DNA_Stranding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ListofimagesListBox             matlab.ui.control.ListBox
        Lamp                            matlab.ui.control.Lamp
        FiberAnalysisPanel              matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        DetectionButton                 matlab.ui.control.Button
        AnalysisButton                  matlab.ui.control.Button
        SaveFibersButton                matlab.ui.control.Button
        BatchProcessPanel               matlab.ui.container.Panel
        BatchprocessDropDown            matlab.ui.control.DropDown
        RunButton                       matlab.ui.control.Button
        LampText                        matlab.ui.control.Label
        DisplayOptionsButtonGroup       matlab.ui.container.ButtonGroup
        RGBbutton                       matlab.ui.control.ToggleButton
        CH1Button                       matlab.ui.control.ToggleButton
        CH2Button                       matlab.ui.control.ToggleButton
        OverlaymaskCheckBox             matlab.ui.control.CheckBox
        Slider                          matlab.ui.control.Slider
        Image10                         matlab.ui.control.Image
        CH3Button                       matlab.ui.control.ToggleButton
        ListLabel                       matlab.ui.control.Label
        LoadDataButton_2                matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        ReadClearSwitch                 matlab.ui.control.RockerSwitch
        ReadLabel                       matlab.ui.control.Label
        ClearLabel                      matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        InputPathEditFieldLabel         matlab.ui.control.Label
        InputpathEditField              matlab.ui.control.EditField
        OutputPathEditFieldLabel        matlab.ui.control.Label
        OutputpathEditField             matlab.ui.control.EditField
        Image4                          matlab.ui.control.Image
        IOPathsLabel                    matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        BiomarkersPanel                 matlab.ui.container.Panel
        stMarkerDropDownLabel           matlab.ui.control.Label
        stMarkerDropDown                matlab.ui.control.DropDown
        ndMarkerDropDownLabel           matlab.ui.control.Label
        ndMarkerDropDown                matlab.ui.control.DropDown
        ColorPanel                      matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        CH2_COLOR                       matlab.ui.control.Lamp
        CH1_COLOR                       matlab.ui.control.Lamp
        Switchcolor                     matlab.ui.control.RockerSwitch
        InputChannelsPanel              matlab.ui.container.Panel
        CH1_DropDown                    matlab.ui.control.DropDown
        CH2_DropDown                    matlab.ui.control.DropDown
        ReplicationTimeminPanel         matlab.ui.container.Panel
        CH1TimeEditField                matlab.ui.control.Spinner
        CH2TimeEditField                matlab.ui.control.Spinner
        ExperimentalSetupLabel          matlab.ui.control.Label
        UserGuideButton                 matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        AnnotationPanel                 matlab.ui.container.Panel
        PencilthicknessSpinner          matlab.ui.control.Spinner
        PencilThicknessLabel            matlab.ui.control.Label
        AnnotColor                      matlab.ui.control.Lamp
        SetColorButton                  matlab.ui.control.Button
        SmoothnessSpinnerLabel          matlab.ui.control.Label
        PencilSmoothnessSpinner         matlab.ui.control.Spinner
        PencilModeDropDownLabel         matlab.ui.control.Label
        PencilModeDropDown              matlab.ui.control.DropDown
        EraserModeLabel                 matlab.ui.control.Label
        EraserModeDropDown              matlab.ui.control.DropDown
        DetectionPanel_2                matlab.ui.container.Panel
        MaxGappxEditFieldLabel          matlab.ui.control.Label
        MaxGappxEditField               matlab.ui.control.NumericEditField
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MinFiberLengthLabel             matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        EditField                       matlab.ui.control.NumericEditField
        DropDown_2                      matlab.ui.control.DropDown
        ImageInfoPanel                  matlab.ui.container.Panel
        PixelSizemLabel                 matlab.ui.control.Label
        pixelsizeumperpixEditField      matlab.ui.control.NumericEditField
        InputfileformatLabel            matlab.ui.control.Label
        FileFormatDropDown              matlab.ui.control.DropDown
        BaseSizekbLabel                 matlab.ui.control.Label
        basesizeEditField               matlab.ui.control.NumericEditField
        DisplayUnitsLabel               matlab.ui.control.Label
        FiberMeasurementsUnitsDropDown  matlab.ui.control.DropDown
        Image14_2                       matlab.ui.control.Image
        Panel_5                         matlab.ui.container.Panel
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        ResetConfigButton               matlab.ui.control.Button
        Image14                         matlab.ui.control.Image
        SetasDefaultButton              matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        Image5                          matlab.ui.control.Image
        OverlayMaskLabel                matlab.ui.control.Label
        OverlayMaskEditField            matlab.ui.control.EditField
        DrawLabel                       matlab.ui.control.Label
        DrawLineEditField               matlab.ui.control.EditField
        EraserEditFieldLabel            matlab.ui.control.Label
        EraserEditField                 matlab.ui.control.EditField
        DisplayChannelEditFieldLabel    matlab.ui.control.Label
        DisplayChannelEditField         matlab.ui.control.EditField
        PreviousImageLabel              matlab.ui.control.Label
        PreviousImageEditField          matlab.ui.control.EditField
        NextImageEditFieldLabel         matlab.ui.control.Label
        NextImageEditField              matlab.ui.control.EditField
        EditLineEditFieldLabel          matlab.ui.control.Label
        EditLineEditField               matlab.ui.control.EditField
        KeyboardShortcutsLabel          matlab.ui.control.Label
        ViewTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        Image6                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Label_ImageName                 matlab.ui.control.Label
        Label_ImageRank                 matlab.ui.control.Label
        TableTab                        matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        UIAxes2                         matlab.ui.control.UIAxes
        Image8                          matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        UIAxes3                         matlab.ui.control.UIAxes
        IDLabel                         matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UITable2                        matlab.ui.control.Table
        CreditsTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        TextArea_Citation               matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        Panel_2                         matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Panel_4                         matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        TextArea_2                      matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Source_Path
        Input_Path
        Output_Path
        Last_Path = '' ;
        SelectedRow = [] ;
        FileFormat
        Im_CH1
        Im_CH2
        Im_CH3
        Im_RGB
        AX_TB
        Im_BW
        Im_Overlay
        Overlayminus1
        Maskminus1
        ROI
        AXES
        Nimages
        Fibers_properties
        tmp1
        tmp2
        tmp3
        CurrentFile
        Listener
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Author(s): Laurent GOLE, Longjie LI
            % Created: 01-Oct-2020
            % Copyright 2020 IMCB, A*STAR.
            
            
            % DEFINE DEFAULT MAIN FIGURE
            app.UIFigure.Visible    = 'off'             ;
            app.UIFigure.Name       = 'DNA_Stranding';
            CodePath = which(mfilename)                 ;
            CodePath = split(CodePath,filesep)          ;
            CodePath = join(CodePath(1:end-1),filesep)  ;
            app.Source_Path          = CodePath{1}      ;
            addpath(genpath(app.Source_Path)) ;
            %ADD useful TAGS :__________________________
            app.UIFigure.Tag        = 'MAINWINDOW'  ;
            InsertAppIcon(app.UIFigure,'DNA_Stranding','DNA_Stranding_logo6.ico')    ;
            app.UIFigure.Visible    = 'on'             ;
            app.UIFigure.WindowState = 'maximized';
            
            
            LampSwitch(app,'on','Loading...') ;
            d = uiprogressdlg(app.UIFigure,'Title','Loading ...',...
                'Indeterminate','on','Icon','DNAGIF.gif');
            
            app.UIAxes.NextPlot = 'replaceall' ;
            app.UIAxes.Clipping = 'on'  ;
            app.UIAxes.ClippingStyle= 'rectangle';
            app.UIAxes.Visible = 'off' ;
            
            % set Icons:
            app.Image4.ImageSource  = 'Open_24.png';
            app.Image.ImageSource = 'imcb_logo.png';
            app.Image2.ImageSource = 'DNA_Stranding_logo4.png' ;
            app.Image3.ImageSource = 'LKSOM Logo.png' ;
            app.Image5.ImageSource = 'keyboard_key_32.png'  ;
            app.Image6.ImageSource = 'hollowLeftArrow.png' ;
            app.Image7.ImageSource = 'hollowRightArrow.png' ;
            app.Image8.ImageSource = 'hollowLeftArrow.png' ;
            app.Image9.ImageSource = 'hollowRightArrow.png' ;
            app.Image14.ImageSource = 'gear_24.png' ;
            app.Image14_2.ImageSource = 'Tools_24.png' ;
            app.UserGuideButton.Icon = 'Help.png' ;
            app.UserGuideButton.IconAlignment = 'left' ;
            app.Image10.ImageSource = 'tool_contrast.png' ;
            app.LoadDataButton.Icon = 'Open_24.png' ;
            app.LoadDataButton.IconAlignment = 'left' ;
            app.LoadDataButton_2.Icon = 'Open_24.png' ;
            app.LoadDataButton_2.IconAlignment = 'left' ;
            %             app.Image13.ImageSource = 'web_icon.png' ;
            % initiate main figure empty for toolbar declaration
            imagesc(app.UIAxes,uint8(zeros([1024,1024,3])))
            app.UIAxes.YDir = 'normal' ;
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'off') ;
            if isempty(app.AX_TB)
                pause(0.1)
                app = Fibers_Toolbar(app) ;
                drawnow
            end
            
            
            % load default config if it exists:
            if  exist([app.Source_Path filesep 'SETTINGS_DNAStranding.csv'],'file')
                T3=   readtable([app.Source_Path filesep 'SETTINGS_DNAStranding.csv'],...
                    'Delimiter','tab','ReadVariableNames',false,"ReadRowNames",true,"PreserveVariableNames",true,"NumHeaderLines",0) ;
                app =loadconfig(T3,app)  ;
            end
            
            drawnow;
            app.UIFigure.Visible = 'on'           ;
            close(d)
            
            LampSwitch(app,'off',' ')
            
        end

        % Value changed function: ListofimagesListBox
        function ListofimagesListBoxValueChanged(app, event)
            
            
            % unnecessary steps to clear Listeners of ROIS:
%             disp('check listneres')
%             Ax = app.AX_TB.Parent ;
%             ROIS = Ax.Children(1:end-1) ; 
%             for i = 1:numel(ROIS)
%                 delete(ROIS(i).AutoListeners__)
%             end
%             
            
            if strcmp(app.ReadClearSwitch.Value,'ClearD')
                %                 delete(app.ListofimagesListBox.Value)
                if ~isempty(app.ListofimagesListBox.Value)
                    IDXtoremove = 0 ;
                    Listofimagestoremove = app.ListofimagesListBox.Value ;
                    for i = 1:numel(app.ListofimagesListBox.Items)
                        for j = 1:numel(Listofimagestoremove)
                            A= strcmp(app.ListofimagesListBox.Items{i},Listofimagestoremove{j}) ;
                            if A==1
                                IDXtoremove(j) = i ;
                            end
                        end
                    end
                    app.ListofimagesListBox.Items(IDXtoremove) = [] ;
                end
                
                app.Nimages =  numel( app.ListofimagesListBox.Items);
                app.ListofimagesListBox.Value = {} ;
                return
            end
            
            
            LampSwitch(app,'on','Reading Image...')
            drawnow
            if exist('event','var')
                d = uiprogressdlg(app.UIFigure,'Title','Reading Image',...
                    'Icon','DNAGIF.gif','Indeterminate','on');
            end
            value = app.ListofimagesListBox.Value;
            if isempty(value)
                LampSwitch(app,'off',' ')
                return
            end
            [~,~,app.FileFormat] = fileparts(app.ListofimagesListBox.Value);
            ImageName = strrep(Dir_Extract(app.ListofimagesListBox.Value,Dir_Num(app.ListofimagesListBox.Value)),app.FileFormat,'') ;
            app.Label_ImageName.Text = ImageName ;
            IDXImage = find (contains(app.ListofimagesListBox.Items,value)) ;
            app.Label_ImageRank.Text = [num2str(IDXImage) ' / ' num2str(app.Nimages) ] ;
            
            % must actually clear all ROIS firsrt no matter what
            if numel(app.UIAxes.Children)>1
                delete(app.UIAxes.Children(1:end-1))
            end
            
            % READ selected Image in List. TODO
            [app.Im_CH1 , app.Im_CH2 , app.Im_CH3, app.Im_RGB, app.Im_BW] =  Read_Image(value,app) ;
            if   strcmp(app.UIAxes.Visible,'off')
                app.UIAxes.Visible = 'on' ;
            end
            
            % DISPLAY selected Image in View Tab.
            Display_Image(app) ;
            
            ImageName = strrep(Dir_Extract(app.ListofimagesListBox.Value,Dir_Num(app.ListofimagesListBox.Value)),app.FileFormat,'') ;
            app.Label_ImageName.Text = ImageName ;
            IDXImage = find (contains(app.ListofimagesListBox.Items,value)) ;
            app.Label_ImageRank.Text = [num2str(IDXImage) ' / ' num2str(app.Nimages) ] ;
            
            %  in case  a rectangle ROI  exists:
            if ~isempty( app.UITable.Data )
                delete(findall(allchild(app.AX_TB.Parent),'Tag','TmpRectangle'));
                % also clear existing tables:
                app.UITable.Data  = [] ;
                app.UITable2.Data  = [] ;
                if ~isempty(  app.UIAxes2.Children )
                    app.UIAxes2.Children(end).CData = [] ;
                end
                cla(app.UIAxes3)
                if ~isempty(  app.UIAxes4.Children )
                    app.UIAxes4.Children(end).CData = [] ;
                end
            end
            
            %  LOAD EXISTING RESULTS AFTER CLEANING:
            FiberOutput_path  = getOutput_path(app) ;
            if exist([FiberOutput_path filesep 'FiberProperties.mat'],'file')==2
                try
                    tmp=  load([FiberOutput_path filesep 'FiberProperties.mat']) ;
                    app.Fibers_properties = table2struct(tmp.FIBPROP) ;
                    % populate Table 1 :
                    app =DNA_Table1(app) ;
                    drawnow
                catch
                    % if version are different loading table will fail.
                end
            end
            
            app.UIAxes.XLim=[0, size(app.Im_RGB,1)];
            app.UIAxes.YLim=[0, size(app.Im_RGB,2)];
            app.CurrentFile = app.ListofimagesListBox.Value ;
            app.ListofimagesListBox.Value = {} ;
            
            LampSwitch(app,'off',' ')
            % close the dialog box
            if exist('event','var')
                close(d);
            end
            
            % to remove created contextmenus ( atteched to figure )by ROIS objects.
            delete(findobj(app.UIFigure,'type','uicontextmenu'))
            
        end

        % Button pushed function: LoadDataButton, LoadDataButton_2
        function LoadDataButtonPushed(app, event)
            
            LampSwitch(app,'on','Loading Images...')
          

            app.Input_Path =  uigetdir(app.Last_Path,['get root folder to search for all .tiff or .tif files']) ;
            if app.Input_Path==0
                figure(app.UIFigure)
                LampSwitch(app,'off',' ')
                return
            else
                app.Last_Path =  app.Input_Path ;
            end
            
            % find all  images with folder and subfolders
            try imds = imageDatastore(app.Input_Path,'FileExtensions',{'.tiff','.tif'},'IncludeSubfolders',true) ;
                % do a quick check of all images and remove wrong images:
                idx = 0;
                IDXdelete=[];
                for k = 1:numel(imds.Files)
                    imf = imfinfo(imds.Files{k}) ;
                    if numel(imf)<1
                        idx = idx+1 ;
                        IDXdelete(idx) = k ; %#ok<AGROW>
                    end
                end
                imds.Files(IDXdelete) = [] ;
            catch
                uialert(app.UIFigure,['No .tiff or .tif images detected in "' app.Input_Path '"'],'Invalid Path');
                figure(app.UIFigure)
                LampSwitch(app,'off',' ')
                return
            end
            
            MAinfoldername = Dir_Extract(app.Input_Path,Dir_Num(app.Input_Path)) ;
            
            %  DEFINE DEFAULT OUTPUT FOLDER:
            app.Output_Path = [Dir_Up(app.Input_Path) filesep MAinfoldername '_RESULTS'] ;
            if ~exist(app.Output_Path,'dir')
                mkdir(app.Output_Path)
            end
            app.OutputpathEditField.Value = app.Output_Path ;
            
            app.ListofimagesListBox.Items = imds.Files ;
            app.Nimages = numel(imds.Files);
            app.InputpathEditField.Value = app.Input_Path ;
            
            LampSwitch(app,'off',' ')
            figure(app.UIFigure) ;
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)


            if isempty(app.ListofimagesListBox.Items)
                return
            end
            
            
            d = uiprogressdlg(app.UIFigure,'Title','Detecting Fibers...',...
                'Icon','DNAGIF.gif','Indeterminate','on');
            LampSwitch(app,'on','Detecting Fibers...')
            delete(app.AX_TB.Parent.Children(1:end-1))
            drawnow
            
            % clear properties fvar before new detection
            app.Fibers_properties = [] ;
            
            if ~(sum(app.Im_CH1(:)))
                disp('image is empty!')
                return
                
            end
            app =  DNA_detection(app) ;
            
            % autosave:
            app.SaveFibersButtonPushed()
            
            LampSwitch(app,'off',' ')
            close(d);
        end

        % Value changed function: OverlaymaskCheckBox
        function OverlaymaskCheckBoxValueChanged(app, event)
            Update_Image2(app);
            drawnow
        end

        % Selection changed function: DisplayOptionsButtonGroup
        function DisplayOptionsButtonGroupSelectionChanged(app, event)
Update_Image2(app) ;
            drawnow
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            % KEYBOARD SHORTCUTS CALLBACKS TODO:
            app = DNAfibers_KEYBOARDSHORTCUTS(app,event) ;
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            % OPEN OUTPUT FOLDER PATH:
            if isfolder(app.OutputpathEditField.Value)
                explorer(app.OutputpathEditField.Value)   ;
            end
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            % GO UP IN LIST :
            app.Image6.ImageSource = 'hollowLeftArrowaccent.png' ;
            IDX = find (contains(app.ListofimagesListBox.Items,app.CurrentFile)) ;
            if IDX>1
                app.ListofimagesListBox.Value = app.ListofimagesListBox.Items(IDX-1);
                evt = [];
                evt.Value = app.ListofimagesListBox.Value ;
                evt.PreviousValue = {} ;
                evt.Source = app.ListofimagesListBox ;
                evt.EventName = 'ValueChanged' ;
                app.ListofimagesListBoxValueChanged(evt)
            end
            app.Image6.ImageSource = 'hollowLeftArrow.png' ;
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            % GO DOWN IN LIST:
            app.Image7.ImageSource = 'hollowRightArrowaccent.png' ;
            IDX = find (contains(app.ListofimagesListBox.Items,app.CurrentFile)) ;
            if IDX<app.Nimages
                app.ListofimagesListBox.Value = app.ListofimagesListBox.Items(IDX+1);
                evt = [];
                evt.Value = app.ListofimagesListBox.Value ;
                evt.PreviousValue = {} ;
                evt.Source = app.ListofimagesListBox ;
                evt.EventName = 'ValueChanged' ;
                app.ListofimagesListBoxValueChanged(evt)
            end
            app.Image7.ImageSource = 'hollowRightArrow.png' ;
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)


            if isempty(app.ListofimagesListBox.Items)
                return
            end
            
            if exist('event','var')
                d = uiprogressdlg(app.UIFigure,'Title','Measuring Fibers properties...',...
                    'Icon','DNAGIF.gif','Indeterminate','on');
            else
                d = [] ;
            end
            
            LampSwitch(app,'on','Measuring  Fibers properties...')
            drawnow


            % MUST INVESTIGATE LISTENERS TODO ?:
            
            
            % Save Current Diplay of Fibers before analysis to tAKE INTO account any possible modifications!:
            app.SaveFibersButtonPushed()
            
            % FIBER MEASUREMENTS CORE ALGORITHM:
            %             tic
            app = DNA_Analysis_ter(app,d) ;
            %             toc
            % TABLE DISPLAY:
            app.UITable.Data = []   ;
            
            if ~isempty(app.Fibers_properties)
                app =DNA_Table1(app) ;
                [profile_table,detail_table] = DNA_Table2save(app) ;
                Op = getOutput_path(app) ;
                Fname = [app.Label_ImageName.Text]       ;
                T2 = saveconfig(app) ;
                
                % output image and table...
                writetable(profile_table,[Op filesep Fname '_Fibers_profile.csv']) ;
                writetable(detail_table,[Op filesep Fname '_Fibers_detail.csv']) ;
                %                 writetable(T2,[Op filesep Fname '_Settings.csv'],'WriteVariableNames',true)
                writetable(T2,[Op filesep Fname '_Settings.csv'],'WriteVariableNames',false,'Delimiter','tab','WriteRowNames',true) ;
            end


            % Save Fibers AFTER Analysis :
            app.SaveFibersButtonPushed()


            LampSwitch(app,'off',' ')
            % close the dialog box
            if exist('event','var')
                close(d);
            end
            
            
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            % PREVIOUS FIBER GO UP IN LIST :
            app.Image8.ImageSource = 'hollowLeftArrowaccent.png' ;
            CurrentN =  str2double(extractAfter(app.IDLabel.Text,'Fiber:'));
            if CurrentN>1
                evt.Indices = [CurrentN-1 2] ;
                evt.DisplayIndices=  [CurrentN-1 2] ;
                evt.Source= app.UITable ;
                evt.EventName= 'CellSelection' ;
                UITableCellSelection(app, evt)
            end
            app.Image8.ImageSource = 'hollowLeftArrow.png' ;
        end

        % Image clicked function: Image9
        function Image9Clicked(app, event)
            % NEXT FIBER:
            app.Image9.ImageSource = 'hollowRightArrowaccent.png' ;
            CurrentN =  str2double(extractAfter(app.IDLabel.Text,'Fiber:'));
            if CurrentN<numel(app.Fibers_properties)
                evt.Indices = [CurrentN+1 2] ;
                evt.DisplayIndices=  [CurrentN+1 2] ;
                evt.Source= app.UITable ;
                evt.EventName= 'CellSelection' ;
                UITableCellSelection(app, evt)
            end
            app.Image9.ImageSource = 'hollowRightArrow.png' ;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            
            LampSwitch(app,'on','Adjusting Image Contrast...')
            changingValue = event.Value;
            % simple Contrast Adjustment tool:
            switch app.DisplayOptionsButtonGroup.SelectedObject
                case app.RGBbutton
                    app.UIAxes.Children(end).CData = imadjust(app.Im_RGB,[0 1-changingValue]) ;
                case app.CH1Button
                    app.UIAxes.Children(end).CData = imadjust(app.Im_CH1,[0 1-changingValue]) ;
                case app.CH2Button
                    app.UIAxes.Children(end).CData = imadjust(app.Im_CH2,[0 1-changingValue]) ;
                case app.CH3Button
                    app.UIAxes.Children(end).CData = imadjust(app.Im_CH3,[0 1-changingValue]) ;
            end
            
            LampSwitch(app,'off',' ')
        end

        % Button pushed function: SaveFibersButton
        function SaveFibersButtonPushed(app, event)


            if isempty(app.ListofimagesListBox.Items)
                return
            end
            
              %add condition so that the lamp doesnt triger when savefiber is
            %called from other pushbuttons.
                   if exist('event','var')
            LampSwitch(app,'on','Measuring  Fibers properties...')
                   end
%             LampSwitch(app,'on','Saving fibers ROIs')
            drawnow
            % convert ROIS to binary mask: binary mask might not be
            % necessary if we use ROI based analysis...
            app = ROIs2BW(app)  ;
            % save Binary Mask:
            FiberOutput_path  = getOutput_path(app) ;
            Fname = [app.Label_ImageName.Text '_BW' app.FileFormat ]       ;
            Fname2 = [app.Label_ImageName.Text '_OL' app.FileFormat ]       ;
            
            if ~isempty(app.Im_BW)
                
                % IMPORTANT REVERSE Y AXIS FOR BETTER DISPLAY IN APP AND CONSITENCY WITH
                % REAL IMAGE:
                ReverseBW = app.Im_BW(end:-1:1,:,:) ;
                imwrite(255*uint8(ReverseBW),[FiberOutput_path filesep Fname]) ;
                %
                [app,OVERLAY] = ROIs2Overlay(app) ;
                %              OVERLAY = OVERLAY(end:-1:1,:,:) ;
                
                imwrite(OVERLAY,[FiberOutput_path filesep Fname2]) ;
                app = save_ROIs(app) ;
                
            else
                LampSwitch(app,'off',' ')
                return
            end
            
            
            %add condition so that the lamp doesnt triger when savefiber is
            %called from other pushbuttons.
            if exist('event','var')
                LampSwitch(app,'off',' ')
            end
%            LampSwitch(app,'off',' ')
        end

        % Callback function
        function BatchAnalysisButtonPushed(app, event)
            % TO CHECK:
            
            LampSwitch(app,'on','Batch Analysis of Fibers...')
            
            drawnow
            d = uiprogressdlg(app.UIFigure,'Title','DNA Strands analysis',...
                'Message','Starting...','Cancelable','on','Icon','DNAGIF.gif');
            pause(.5)
            
            for j = 1:app.Nimages
                
                if d.CancelRequested
                    break
                end
                d.Value = (j-1)/app.Nimages ;
                
                d.Message = {['Fiber ID: ' num2str(j)  ' / ' num2str(app.Nimages) '.' ];...
                    [strrep(Dir_Extract( app.ListofimagesListBox.Items{j},Dir_Num( app.ListofimagesListBox.Items{j})),app.FileFormatDropDown.Value,'')]};
                
                value = app.ListofimagesListBox.Items{j};
                app.ListofimagesListBox.Value = value ;
                if isempty(value)
                    continue
                end
                % this part ot read each images
                app.ListofimagesListBoxValueChanged()

                % FIBER MEASUREMENTS CORE ALGORITHM:
                LampSwitch(app,'on','Measuring  Fibers properties...')
                drawnow
                app = DNA_Analysis_ter(app) ;
                
                % TABLE DISPLAY:
                app.UITable.Data = []   ;
                if ~isempty(app.Fibers_properties)
                    app =DNA_Table1(app) ;
                end

            end
            
            close(d)  ;
            LampSwitch(app,'off',' ')
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            indices = event.Indices;
            
            %             app.UITable.Selection
            %
            
            if ~isempty(indices)
                
                if size(indices,1)==1
                    row =  indices(1) ;
                    if  size(app.UITable.Data,1)>1
                        app.UITable.Selection = [row.* ones([1,13])', (1:13)'] ;
                    end
                end
                
                
                row =  indices(1) ;
                
            else
                return
            end
            
            if row ~= app.SelectedRow
                %  going left or up
                if row<app.SelectedRow
                    app.Image8.ImageSource = 'hollowLeftArrowaccent.png' ;
                    
                elseif row>app.SelectedRow
                    app.Image9.ImageSource = 'hollowRightArrowaccent.png' ;
                    
                end
            end
            
            IDX =   app.UITable.Data.Fiber_No(row) ;
            
            % draw Rectangle around selected Fiber
            app =  DNA_IntensityPlot(app,IDX)   ;
            app.IDLabel.Text =  [' Fiber: '  num2str(IDX)]  ;
            
            % Colors Table:
            try
            app =DNA_Table2(app,IDX) ;
            catch
            end
            app.Image8.ImageSource = 'hollowLeftArrow.png' ;
            app.Image9.ImageSource = 'hollowRightArrow.png' ;


            app.SelectedRow =row;
            
        end

        % Value changed function: FiberMeasurementsUnitsDropDown
        function FiberMeasurementsUnitsDropDownValueChanged(app, event)
            value = app.FiberMeasurementsUnitsDropDown.Value;
            if ~isempty(app.Fibers_properties)
                app =  DNA_Table1(app) ;
            end
        end

        % Button pushed function: SetColorButton
        function SetColorButtonPushed(app, event)
            NewColor =   uisetcolor(app.AnnotColor.Color) ;
            app.AnnotColor.Color = NewColor ;
            figure(app.UIFigure)
        end

        % Value changed function: CH1_DropDown
        function CH1_DropDownValueChanged(app, event)
         app.CH2_DropDown.Value = event.PreviousValue ;
        end

        % Value changed function: CH2_DropDown
        function CH2_DropDownValueChanged(app, event)
            value = app.CH2_DropDown.Value;
            app.CH1_DropDown.Value = event.PreviousValue ;
        end

        % Callback function
        function fluorescentdyeButtonPushed(app, event)
            if isequal(app.CH1_COLOR.Color,[1,0,0])
                app.CH1_COLOR.Color = [0,1,0];
                app.CH2_COLOR.Color = [1,0,0];
            else
                app.CH1_COLOR.Color = [1,0,0];
                app.CH2_COLOR.Color = [0,1,0];
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
%             value = app.Switchcolor.Value;
            if isequal(app.CH1_COLOR.Color,[1,0,0])
                app.CH1_COLOR.Color = [0,1,0];
                app.CH2_COLOR.Color = [1,0,0];
                
            else
                app.CH1_COLOR.Color = [1,0,0];
                app.CH2_COLOR.Color = [0,1,0];
                
            end
        end

        % Value changed function: stMarkerDropDown
        function stMarkerDropDownValueChanged(app, event)
            value = app.stMarkerDropDown.Value;
            
            if strcmp(app.ndMarkerDropDown.Value,'None')
                app.CH1Button.Text = value ;
            else
                switch value
                    
                    case 'IdU'
                        app.ndMarkerDropDown.Value  = 'CldU' ;
                        app.CH1Button.Text = value ;
                        app.CH2Button.Text = 'CldU';
                        
                    case 'CldU'
                        app.ndMarkerDropDown.Value  = 'IdU' ;
                        app.CH1Button.Text = value ;
                        app.CH2Button.Text = 'IdU';
                        
                        
                end
                
            end
            
            %
            %                switch value
            %
            %                 case 'CldU'
            %                     app.stMarkerDropDown.Value  = 'IdU' ;
            %                     app.CH2Button.Text = value ;
            %                     app.CH1Button.Text = 'IdU';
            %                 case 'IdU'
            %                      app.stMarkerDropDown.Value  = 'CldU' ;
            %                     app.CH2Button.Text = value ;
            %                     app.CH1Button.Text = 'CldU';
            %
            %                 case 'None'
            %                      app.CH2Button.Text = value ;
            %
            %             end
            %
            
            
            %             app.CH1Button.Text = value ;
            %             if ~strcmp(app.CH2Button.Text,'None')
            %                 app.ndMarkerDropDown.Value = event.PreviousValue ;
            %                 app.CH2Button.Text = event.PreviousValue ;
            %             end
            %
            %                 value = app.stMarkerDropDown.Value;
            %
            %             switch value
            %
            %                 case 'CldU'
            %                     app.stMarkerDropDown.Value  = 'IdU' ;
            %                     app.CH2Button.Text = value ;
            %                     app.CH1Button.Text = 'IdU';
            %                 case 'IdU'
            %                      app.stMarkerDropDown.Value  = 'CldU' ;
            %                     app.CH2Button.Text = value ;
            %                     app.CH1Button.Text = 'CldU';
            %
            %                 case 'None'
            %                      app.CH2Button.Text = value ;
            %
            %             end
            %
            %
            %
            %


        end

        % Value changed function: ndMarkerDropDown
        function ndMarkerDropDownValueChanged(app, event)
            value = app.ndMarkerDropDown.Value;
            
            switch value
                
                case 'CldU'
                    app.stMarkerDropDown.Value  = 'IdU' ;
                    app.CH2Button.Text = value ;
                    app.CH1Button.Text = 'IdU';
                case 'IdU'
                    app.stMarkerDropDown.Value  = 'CldU' ;
                    app.CH2Button.Text = value ;
                    app.CH1Button.Text = 'CldU';
                    
                case 'None'
                    app.CH2Button.Text = value ;
                    
            end


            %
            %
            %             if ~strcmp(value,'None')
            %                 if ~strcmp(event.PreviousValue,'None')
            %                 app.stMarkerDropDown.Value = event.PreviousValue ;
            %                 app.CH2Button.Text = value ;
            %                 app.CH1Button.Text = event.PreviousValue ;
            %                 else
            %                    switch value
            %                        case 'CldU'
            %                             app.stMarkerDropDown.Value = 'IdU' ;
            %                              app.CH1Button.Text = 'IdU' ;
            %                        case 'IdU'
            %                            app.stMarkerDropDown.Value = 'CldU' ;
            %                            app.CH1Button.Text =  'CldU' ;
            %                    end
            %
            %                 end
            %             else
            %                 app.CH2Button.Text = value ;
            %             end
            %


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)


            if isempty(app.ListofimagesListBox.Items)
                return
            end
            
            app.ReadClearSwitch.Value = 'ReadD' ;
            
            switch  app.ReadClearSwitch.Value
                
                case 'ReadD'
                    app.ListofimagesListBox.Multiselect = 'off' ;
                    app.ReadLabel.FontWeight = 'bold' ;
                    app.ClearLabel.FontWeight = 'normal' ;
                case 'ClearD'
                    app.ListofimagesListBox.Multiselect = 'on' ;
                    app.ReadLabel.FontWeight = 'normal' ;
                    app.ClearLabel.FontWeight = 'bold' ;
            end


            switch app.BatchprocessDropDown.Value
                
                case app.BatchprocessDropDown.Items{1}
                    LampSwitch(app,'on','Batch Detection of Fibers...')
                    
                    
                    drawnow
                    d = uiprogressdlg(app.UIFigure,'Title','DNA Strands batch detection',...
                        'Message','Starting...','Cancelable','on','Icon','DNAGIF.gif');
                    pause(.5)
                    
                    for j = 1:app.Nimages
                        
                        if d.CancelRequested
                            break
                        end
                        d.Value = j/app.Nimages ;
                        
                        
                        value = app.ListofimagesListBox.Items{j};
                        [~,~,app.FileFormat] = fileparts(value);
                        
                        
                        d.Message = {['Image ID: ' num2str(j)  ' / ' num2str(app.Nimages) '.' ];...
                            [strrep(Dir_Extract(value,Dir_Num(value)),app.FileFormat,'')]};
                        d.Value = j/app.Nimages ;
                        
                        app.ListofimagesListBox.Value = value ;
                        if isempty(value)
                            continue
                        end
                        % Read the image:
                        app.ListofimagesListBoxValueChanged()
                        
                        % clean all Axes children
                        delete(app.AX_TB.Parent.Children(1:end-1))
                        
                        % detect fibers
                        app = DNA_detection(app) ;
                        
                        % save fibers
                        app.SaveFibersButtonPushed()
                        
                    end
                    close(d)  ;
                    LampSwitch(app,'off',' ')


                case app.BatchprocessDropDown.Items{2}
                    
                    
                    LampSwitch(app,'on','Batch Analysis of Fibers...')
                    
                    drawnow
                    d = uiprogressdlg(app.UIFigure,'Title','DNA Strands analysis',...
                        'Message','Starting...','Cancelable','on','Icon','DNAGIF.gif');
                    pause(.5)
                    
                    for j = 1:app.Nimages
                        
                        if d.CancelRequested
                            break
                        end
                        value = app.ListofimagesListBox.Items{j};
                        [~,~,app.FileFormat] = fileparts(value);
                        d.Message = {['Image ID: ' num2str(j)  ' / ' num2str(app.Nimages) '.' ];...
                            [strrep(Dir_Extract(value,Dir_Num(value)),app.FileFormat,'')]};
                        d.Value = j/app.Nimages ;
                        app.ListofimagesListBox.Value = value ;
                        if isempty(value)
                            continue
                        end
                        % this part to read  images:
                        app.ListofimagesListBoxValueChanged()
                        
                        % NOTE THE FIBERS DETECTION ROIS SHOULD ALL EXIST ALREADY TO
                        % DO ANALYSIS BATCH ONLY!
                        
                        app.AnalysisButtonPushed()


                    GENERATEBATCHTABLE(app,[])
                                   %% ADD COMBIEND TABLE HERE : 
%                                 
%                 % output image and table...
%                 writetable(profile_table,[Op filesep Fname '_Fibers_profile.csv']) ;
%                 writetable(detail_table,[Op filesep Fname '_Fibers_detail.csv']) ;
%                 %                 writetable(T2,[Op filesep Fname '_Settings.csv'],'WriteVariableNames',true)
%                 writetable(T2,[Op filesep Fname '_Settings.csv'],'WriteVariableNames',false,'Delimiter','tab','WriteRowNames',true) ;
%                         
%                         
%                         


                    end
                    
                    close(d)  ;
                    LampSwitch(app,'off',' ')


                case app.BatchprocessDropDown.Items{3}
                    % DETECTION AND ANALYSIS
                    
                    LampSwitch(app,'on','Batch Detection of Fibers...')
                    
                    drawnow
                    d = uiprogressdlg(app.UIFigure,'Title','DNA Strands batch detection & Analysis',...
                        'Message','Starting...','Cancelable','on','Icon','DNAGIF.gif');
                    pause(.5)
                    
                    for j = 1:app.Nimages
                        
                        if d.CancelRequested
                            break
                        end
                        value = app.ListofimagesListBox.Items{j};
                        [~,~,app.FileFormat] = fileparts(value);
                        d.Message = {['Image ID: ' num2str(j)  ' / ' num2str(app.Nimages) '.' ];...
                            [strrep(Dir_Extract(value,Dir_Num(value)),app.FileFormat,'')]};
                        d.Value = j/app.Nimages ;
                        app.ListofimagesListBox.Value = value ;
                        if isempty(value)
                            continue
                        end
                        % READ THE IMAGE:
                        app.ListofimagesListBoxValueChanged()
                        
                        % to delete all existing Axes children if any
                        delete(app.AX_TB.Parent.Children(1:end-1))
                        
                        % detect fibers
                        app = DNA_detection(app) ;
                        %save fibers:
                        app.SaveFibersButtonPushed() ;
                        
                        app.AnalysisButtonPushed() ;
                        
                        %% ADD COMBIEND TABLE HERE : 


                    GENERATEBATCHTABLE(app,[])
                        
%                                 
%                 % output image and table...
%                 writetable(profile_table,[Op filesep Fname '_Fibers_profile.csv']) ;
%                 writetable(detail_table,[Op filesep Fname '_Fibers_detail.csv']) ;
%                 %                 writetable(T2,[Op filesep Fname '_Settings.csv'],'WriteVariableNames',true)
%                 writetable(T2,[Op filesep Fname '_Settings.csv'],'WriteVariableNames',false,'Delimiter','tab','WriteRowNames',true) ;
%                         
%                         
%                         


                    end
                    
                    close(d)  ;
                    LampSwitch(app,'off',' ')
                    
                    
                     case app.BatchprocessDropDown.Items{4}   
                    
                         % GATHER TABLES: 
                         
                          LampSwitch(app,'on','Batch Results of Fibers Analysis...')
                    
                    drawnow
                    d = uiprogressdlg(app.UIFigure,'Title','DNA Strands gathering tables',...
                        'Message','Starting...','Cancelable','on','Icon','DNAGIF.gif');
                    pause(.5)
                    
                    GENERATEBATCHTABLE(app,d)
                    
                       close(d)  ;
                    LampSwitch(app,'off',' ')
                    
           
            end


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            pxumval =  app.pixelsizeumperpixEditField.Value ;
            kbval = app.basesizeEditField.Value ;
            
            switch value
                
                case app.DropDown.Items{1}
                    switch event.PreviousValue
                        case app.DropDown.Items{1}
                            app.EditField.Value = app.EditField.Value;
                        case app.DropDown.Items{2}
                            app.EditField.Value = app.EditField.Value /pxumval;
                        case app.DropDown.Items{3}
                            app.EditField.Value = app.EditField.Value /pxumval / kbval;
                    end
                    
                case app.DropDown.Items{2}
                    switch event.PreviousValue
                        case app.DropDown.Items{1}
                            app.EditField.Value = app.EditField.Value * pxumval;
                        case app.DropDown.Items{2}
                            app.EditField.Value = app.EditField.Value ;
                        case app.DropDown.Items{3}
                            app.EditField.Value = app.EditField.Value  / kbval;
                    end
                    
                case app.DropDown.Items{3}
                    switch event.PreviousValue
                        case app.DropDown.Items{1}
                            app.EditField.Value = app.EditField.Value * pxumval * kbval;
                        case app.DropDown.Items{2}
                            app.EditField.Value = app.EditField.Value * kbval ;
                        case app.DropDown.Items{3}
                            app.EditField.Value = app.EditField.Value  ;
                    end
            end
            
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            pxumval =  app.pixelsizeumperpixEditField.Value ;
            kbval = app.basesizeEditField.Value ;
            switch value
                case app.DropDown_2.Items{1}
                    switch event.PreviousValue
                        case app.DropDown_2.Items{1}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value;
                        case app.DropDown_2.Items{2}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value /pxumval;
                        case app.DropDown_2.Items{3}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value /pxumval / kbval;
                    end
                case app.DropDown_2.Items{2}
                    switch event.PreviousValue
                        case app.DropDown_2.Items{1}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value * pxumval;
                        case app.DropDown_2.Items{2}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value ;
                        case app.DropDown_2.Items{3}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value  / kbval;
                    end
                case app.DropDown_2.Items{3}
                    switch event.PreviousValue
                        case app.DropDown_2.Items{1}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value * pxumval * kbval;
                        case app.DropDown_2.Items{2}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value * kbval ;
                        case app.DropDown_2.Items{3}
                            app.MaxGappxEditField.Value = app.MaxGappxEditField.Value  ;
                    end
            end
            
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            %%
            
            T2 = saveconfig(app) ;
            
            [conffile, confpath] = uiputfile([app.Last_Path filesep 'SETTINGS_DNAStranding.csv'],'Save config file for DNA Stranding') ;
            if confpath~=0
                writetable(T2,...
                    [confpath  conffile],'WriteVariableNames',false,'Delimiter','tab','WriteRowNames',true)
            end
            
        end

        % Button pushed function: ResetConfigButton
        function ResetConfigButtonPushed(app, event)
            app = resetconfig(app) ;
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            [conffile, confpath] = uigetfile([app.Last_Path filesep 'SETTINGS_DNAStranding.csv'],'Save config file for DNA Stranding');
            
            if confpath~=0
                T3=   readtable([confpath  conffile],'Delimiter','tab','ReadVariableNames',false,"ReadRowNames",true,"PreserveVariableNames",true,"NumHeaderLines",0) ;
              app =loadconfig(T3,app)  ;
            end
        
            
        end

        % Value changed function: Switchcolor
        function SwitchcolorValueChanged(app, event)
            value = app.Switchcolor.Value;
            
            if isequal(app.CH1_COLOR.Color,[1,0,0])
                app.CH1_COLOR.Color = [0,1,0];
                app.CH2_COLOR.Color = [1,0,0];
                
                if ~isempty(app.Im_CH1)
                    app.Im_CH1 = switchColors(app.Im_CH1,'grb') ;
                    app.Im_CH2 =  switchColors(app.Im_CH2,'grb') ;
                    
                    app.Im_RGB = cat(3, sum(app.Im_CH1,3),sum(app.Im_CH2,3), app.Im_RGB(:,:,3));
                    
                end
                
            else
                app.CH1_COLOR.Color = [1,0,0];
                app.CH2_COLOR.Color = [0,1,0];
                if ~isempty(app.Im_CH1)
                    app.Im_CH1 = switchColors(app.Im_CH1,'rgb') ;
                    app.Im_CH2 =  switchColors(app.Im_CH2,'rgb') ;
                    
                    app.Im_RGB = cat(3, sum(app.Im_CH1,3),sum(app.Im_CH2,3), app.Im_RGB(:,:,3));


                end
                
            end
            app=   Display_Image(app)       ;
            
            
        end

        % Button pushed function: UserGuideButton
        function UserGuideButtonPushed(app, event)
            
            
            if exist([app.Source_Path filesep  'License' filesep 'DNA_Stranding_UserGuide.pdf'],'file')
                winopen([app.Source_Path filesep  'License' filesep 'DNA_Stranding_UserGuide.pdf']) ;
                
            end
            
        end

        % Callback function
        function Image13Clicked(app, event)
            
            web('https://www.a-star.edu.sg/imcb/imcb-research/scientific-programmes/innovative-technologies')
            
        end

        % Callback function
        function ClearfileButtonValueChanged(app, event)
            value = app.ClearfileButton.Value;
            
            
            switch value
                
                case false
                    app.ListofimagesListBox.Multiselect = 'off' ;
                case true
                    app.ListofimagesListBox.Multiselect = 'on' ;
                    
            end
            
        end

        % Value changed function: ReadClearSwitch
        function ReadClearSwitchValueChanged(app, event)
            value = app.ReadClearSwitch.Value;
            
            switch value
                
                case 'ReadD'
                    app.ListofimagesListBox.Multiselect = 'off' ;
                    app.ReadLabel.FontWeight = 'bold' ;
                    app.ClearLabel.FontWeight = 'normal' ;
                case 'ClearD'
                    app.ListofimagesListBox.Multiselect = 'on' ;
                    app.ReadLabel.FontWeight = 'normal' ;
                    app.ClearLabel.FontWeight = 'bold' ;
            end


        end

        % Button pushed function: SetasDefaultButton
        function SetasDefaultButtonPushed(app, event)
            %%
            
            T2 = saveconfig(app) ;
            
            writetable(T2,...
                [app.Source_Path filesep 'SETTINGS_DNAStranding.csv'],'WriteVariableNames',false,'Delimiter','tab','WriteRowNames',true)
            
            %
            %             [conffile, confpath] = uiputfile([app.Last_Path filesep 'SETTINGS_DNAStranding.csv'],'Save config file for DNA Stranding') ;
            %             if confpath~=0
            %                 writetable(T2,...
            %                     [confpath  conffile],'WriteVariableNames',false,'Delimiter','tab','WriteRowNames',true)
            %             end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {307, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ListofimagesListBox = uilistbox(app.GridLayout2);
            app.ListofimagesListBox.Items = {};
            app.ListofimagesListBox.ValueChangedFcn = createCallbackFcn(app, @ListofimagesListBoxValueChanged, true);
            app.ListofimagesListBox.Value = {};
            app.Lamp = uilamp(app.GridLayout2);
            app.FiberAnalysisPanel = uipanel(app.GridLayout2);
            app.FiberAnalysisPanel.Title = 'Fiber Analysis:';
            app.GridLayout7 = uigridlayout(app.FiberAnalysisPanel);
            app.DetectionButton = uibutton(app.GridLayout7, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Text = 'Detection';
            app.AnalysisButton = uibutton(app.GridLayout7, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.SaveFibersButton = uibutton(app.GridLayout7, 'push');
            app.SaveFibersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFibersButtonPushed, true);
            app.SaveFibersButton.Text = 'Save Fibers';
            app.BatchProcessPanel = uipanel(app.GridLayout7);
            app.BatchProcessPanel.Title = 'Batch Process:';
            app.BatchprocessDropDown = uidropdown(app.BatchProcessPanel);
            app.BatchprocessDropDown.Items = {'Detection', 'Analysis', 'Detection & Analysis', 'Gather Tables'};
            app.BatchprocessDropDown.Value = 'Detection & Analysis';
            app.RunButton = uibutton(app.BatchProcessPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LampText = uilabel(app.GridLayout2);
            app.LampText.Text = 'Welcome to DNA Stranding.';
            app.DisplayOptionsButtonGroup = uibuttongroup(app.GridLayout2);
            app.DisplayOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayOptionsButtonGroupSelectionChanged, true);
            app.DisplayOptionsButtonGroup.Title = 'Display Options:';
            app.RGBbutton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.RGBbutton.Text = 'RGB';
            app.RGBbutton.Value = true;
            app.CH1Button = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.CH1Button.Text = 'CldU';
            app.CH2Button = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.CH2Button.Text = 'IdU';
            app.OverlaymaskCheckBox = uicheckbox(app.DisplayOptionsButtonGroup);
            app.OverlaymaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaymaskCheckBoxValueChanged, true);
            app.OverlaymaskCheckBox.Text = 'Overlay Mask';
            app.OverlaymaskCheckBox.Value = true;
            app.Slider = uislider(app.DisplayOptionsButtonGroup);
            app.Slider.Limits = [0 0.999];
            app.Slider.MajorTicks = [0 0.25 0.5 0.75 0.999];
            app.Slider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 0.999];
            app.Image10 = uiimage(app.DisplayOptionsButtonGroup);
            app.CH3Button = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.CH3Button.Text = 'CH3';
            app.ListLabel = uilabel(app.GridLayout2);
            app.ListLabel.Text = 'List:';
            app.LoadDataButton_2 = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton_2.Text = 'Load Data';
            app.Panel_10 = uipanel(app.GridLayout2);
            app.ReadClearSwitch = uiswitch(app.Panel_10, 'rocker');
            app.ReadClearSwitch.Items = {' ', '  '};
            app.ReadClearSwitch.ItemsData = {'ReadD', 'ClearD'};
            app.ReadClearSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadClearSwitchValueChanged, true);
            app.ReadClearSwitch.Value = 'ReadD';
            app.ReadLabel = uilabel(app.Panel_10);
            app.ReadLabel.Text = 'Read';
            app.ClearLabel = uilabel(app.Panel_10);
            app.ClearLabel.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout14 = uigridlayout(app.SettingsTab);
            app.Panel_9 = uipanel(app.GridLayout14);
            app.InputPathEditFieldLabel = uilabel(app.Panel_9);
            app.InputPathEditFieldLabel.Text = 'Input Path: ';
            app.InputpathEditField = uieditfield(app.Panel_9, 'text');
            app.OutputPathEditFieldLabel = uilabel(app.Panel_9);
            app.OutputPathEditFieldLabel.Text = 'Output Path:';
            app.OutputpathEditField = uieditfield(app.Panel_9, 'text');
            app.Image4 = uiimage(app.Panel_9);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.IOPathsLabel = uilabel(app.Panel_9);
            app.IOPathsLabel.Text = 'I/O Paths:';
            app.LoadDataButton = uibutton(app.Panel_9, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Panel_7 = uipanel(app.GridLayout14);
            app.BiomarkersPanel = uipanel(app.Panel_7);
            app.BiomarkersPanel.Title = 'Biomarkers:';
            app.stMarkerDropDownLabel = uilabel(app.BiomarkersPanel);
            app.stMarkerDropDownLabel.Text = '1st Marker:';
            app.stMarkerDropDown = uidropdown(app.BiomarkersPanel);
            app.stMarkerDropDown.Items = {'CldU', 'IdU'};
            app.stMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @stMarkerDropDownValueChanged, true);
            app.stMarkerDropDown.Value = 'CldU';
            app.ndMarkerDropDownLabel = uilabel(app.BiomarkersPanel);
            app.ndMarkerDropDownLabel.Text = '2nd Marker:';
            app.ndMarkerDropDown = uidropdown(app.BiomarkersPanel);
            app.ndMarkerDropDown.Items = {'CldU', 'IdU', 'None', ''};
            app.ndMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @ndMarkerDropDownValueChanged, true);
            app.ndMarkerDropDown.Value = 'IdU';
            app.ColorPanel = uipanel(app.Panel_7);
            app.ColorPanel.Title = 'Color:';
            app.Label_2 = uilabel(app.ColorPanel);
            app.Label_2.Text = '';
            app.CH2_COLOR = uilamp(app.ColorPanel);
            app.CH1_COLOR = uilamp(app.ColorPanel);
            app.Switchcolor = uiswitch(app.ColorPanel, 'rocker');
            app.Switchcolor.Items = {'.', '..'};
            app.Switchcolor.ValueChangedFcn = createCallbackFcn(app, @SwitchcolorValueChanged, true);
            app.Switchcolor.Value = '.';
            app.InputChannelsPanel = uipanel(app.Panel_7);
            app.InputChannelsPanel.Title = 'Input Channels:';
            app.CH1_DropDown = uidropdown(app.InputChannelsPanel);
            app.CH1_DropDown.Items = {'CH1', 'CH2'};
            app.CH1_DropDown.ValueChangedFcn = createCallbackFcn(app, @CH1_DropDownValueChanged, true);
            app.CH1_DropDown.Value = 'CH1';
            app.CH2_DropDown = uidropdown(app.InputChannelsPanel);
            app.CH2_DropDown.Items = {'CH1', 'CH2'};
            app.CH2_DropDown.ValueChangedFcn = createCallbackFcn(app, @CH2_DropDownValueChanged, true);
            app.CH2_DropDown.Value = 'CH2';
            app.ReplicationTimeminPanel = uipanel(app.Panel_7);
            app.ReplicationTimeminPanel.Title = 'Replication Time (min):';
            app.CH1TimeEditField = uispinner(app.ReplicationTimeminPanel);
            app.CH1TimeEditField.Limits = [1 Inf];
            app.CH1TimeEditField.RoundFractionalValues = 'on';
            app.CH1TimeEditField.Value = 30;
            app.CH2TimeEditField = uispinner(app.ReplicationTimeminPanel);
            app.CH2TimeEditField.Limits = [1 Inf];
            app.CH2TimeEditField.RoundFractionalValues = 'on';
            app.CH2TimeEditField.Value = 30;
            app.ExperimentalSetupLabel = uilabel(app.Panel_7);
            app.ExperimentalSetupLabel.Text = 'Experimental Setup:';
            app.UserGuideButton = uibutton(app.Panel_7, 'push');
            app.UserGuideButton.ButtonPushedFcn = createCallbackFcn(app, @UserGuideButtonPushed, true);
            app.UserGuideButton.Text = 'User Guide';
            app.Panel_6 = uipanel(app.GridLayout14);
            app.AnnotationPanel = uipanel(app.Panel_6);
            app.AnnotationPanel.Title = 'Annotation:';
            app.PencilthicknessSpinner = uispinner(app.AnnotationPanel);
            app.PencilthicknessSpinner.Limits = [1 10];
            app.PencilthicknessSpinner.Value = 2;
            app.PencilThicknessLabel = uilabel(app.AnnotationPanel);
            app.PencilThicknessLabel.Text = 'Pencil Thickness:';
            app.AnnotColor = uilamp(app.AnnotationPanel);
            app.SetColorButton = uibutton(app.AnnotationPanel, 'push');
            app.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorButtonPushed, true);
            app.SetColorButton.Text = 'Set Color';
            app.SmoothnessSpinnerLabel = uilabel(app.AnnotationPanel);
            app.SmoothnessSpinnerLabel.Text = 'Smoothness:';
            app.PencilSmoothnessSpinner = uispinner(app.AnnotationPanel);
            app.PencilSmoothnessSpinner.Limits = [0 10];
            app.PencilSmoothnessSpinner.Value = 3;
            app.PencilModeDropDownLabel = uilabel(app.AnnotationPanel);
            app.PencilModeDropDownLabel.Text = 'Pencil Mode:';
            app.PencilModeDropDown = uidropdown(app.AnnotationPanel);
            app.PencilModeDropDown.Items = {'Freehand', 'PolyLine', 'Snap Freehand'};
            app.PencilModeDropDown.Value = 'Freehand';
            app.EraserModeLabel = uilabel(app.AnnotationPanel);
            app.EraserModeLabel.Text = 'Eraser Mode:';
            app.EraserModeDropDown = uidropdown(app.AnnotationPanel);
            app.EraserModeDropDown.Items = {'Object', 'Area'};
            app.EraserModeDropDown.Value = 'Object';
            app.DetectionPanel_2 = uipanel(app.Panel_6);
            app.DetectionPanel_2.Title = 'Detection:';
            app.MaxGappxEditFieldLabel = uilabel(app.DetectionPanel_2);
            app.MaxGappxEditFieldLabel.Text = 'Max  Gap (px):';
            app.MaxGappxEditField = uieditfield(app.DetectionPanel_2, 'numeric');
            app.MaxGappxEditField.Limits = [0 Inf];
            app.MaxGappxEditField.Value = 10;
            app.MethodDropDownLabel = uilabel(app.DetectionPanel_2);
            app.MethodDropDownLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.DetectionPanel_2);
            app.MethodDropDown.Items = {'Frangi GMM', 'Frangi Otsu', 'GMM', 'Otsu', 'Optimal'};
            app.MethodDropDown.Value = 'Optimal';
            app.MinFiberLengthLabel = uilabel(app.DetectionPanel_2);
            app.MinFiberLengthLabel.Text = 'Min Fiber Length:';
            app.DropDown = uidropdown(app.DetectionPanel_2);
            app.DropDown.Items = {'pixels', 'µm', 'kb'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'pixels';
            app.EditField = uieditfield(app.DetectionPanel_2, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 20;
            app.DropDown_2 = uidropdown(app.DetectionPanel_2);
            app.DropDown_2.Items = {'pixels', 'µm', 'kb'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'pixels';
            app.ImageInfoPanel = uipanel(app.Panel_6);
            app.ImageInfoPanel.Title = 'Image Info:';
            app.PixelSizemLabel = uilabel(app.ImageInfoPanel);
            app.PixelSizemLabel.Text = 'Pixel Size (µm):';
            app.pixelsizeumperpixEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.pixelsizeumperpixEditField.Limits = [0 Inf];
            app.pixelsizeumperpixEditField.Value = 0.103;
            app.InputfileformatLabel = uilabel(app.ImageInfoPanel);
            app.InputfileformatLabel.Enable = 'off';
            app.InputfileformatLabel.Visible = 'off';
            app.InputfileformatLabel.Text = 'Input file format: ';
            app.FileFormatDropDown = uidropdown(app.ImageInfoPanel);
            app.FileFormatDropDown.Items = {'.tif', '.tiff', '.czi', '.png', ''};
            app.FileFormatDropDown.Enable = 'off';
            app.FileFormatDropDown.Visible = 'off';
            app.FileFormatDropDown.Tooltip = {'file input must be .tiff or .tif'};
            app.FileFormatDropDown.Value = '.tif';
            app.BaseSizekbLabel = uilabel(app.ImageInfoPanel);
            app.BaseSizekbLabel.Text = 'Base Size (kb): ';
            app.basesizeEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.basesizeEditField.Limits = [0 Inf];
            app.basesizeEditField.Value = 2.59;
            app.DisplayUnitsLabel = uilabel(app.ImageInfoPanel);
            app.DisplayUnitsLabel.Text = 'Display Units: ';
            app.FiberMeasurementsUnitsDropDown = uidropdown(app.ImageInfoPanel);
            app.FiberMeasurementsUnitsDropDown.Items = {'pixels', 'µm', 'kb'};
            app.FiberMeasurementsUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @FiberMeasurementsUnitsDropDownValueChanged, true);
            app.FiberMeasurementsUnitsDropDown.Value = 'µm';
            app.Image14_2 = uiimage(app.Panel_6);
            app.Panel_5 = uipanel(app.GridLayout14);
            app.SaveConfigButton = uibutton(app.Panel_5, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.Panel_5, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ResetConfigButton = uibutton(app.Panel_5, 'push');
            app.ResetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ResetConfigButtonPushed, true);
            app.ResetConfigButton.Text = 'Reset Config';
            app.Image14 = uiimage(app.Panel_5);
            app.SetasDefaultButton = uibutton(app.Panel_5, 'push');
            app.SetasDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetasDefaultButtonPushed, true);
            app.SetasDefaultButton.Text = 'Set as Default';
            app.Panel_8 = uipanel(app.GridLayout14);
            app.Image5 = uiimage(app.Panel_8);
            app.OverlayMaskLabel = uilabel(app.Panel_8);
            app.OverlayMaskLabel.Text = 'Overlay Mask:';
            app.OverlayMaskEditField = uieditfield(app.Panel_8, 'text');
            app.OverlayMaskEditField.Value = 'm';
            app.DrawLabel = uilabel(app.Panel_8);
            app.DrawLabel.Text = 'Draw:';
            app.DrawLineEditField = uieditfield(app.Panel_8, 'text');
            app.DrawLineEditField.Value = 'd';
            app.EraserEditFieldLabel = uilabel(app.Panel_8);
            app.EraserEditFieldLabel.Text = 'Eraser:';
            app.EraserEditField = uieditfield(app.Panel_8, 'text');
            app.EraserEditField.Value = 'e';
            app.DisplayChannelEditFieldLabel = uilabel(app.Panel_8);
            app.DisplayChannelEditFieldLabel.Text = 'Display Channel:';
            app.DisplayChannelEditField = uieditfield(app.Panel_8, 'text');
            app.DisplayChannelEditField.Value = 'q';
            app.PreviousImageLabel = uilabel(app.Panel_8);
            app.PreviousImageLabel.Text = 'Previous Image: ';
            app.PreviousImageEditField = uieditfield(app.Panel_8, 'text');
            app.PreviousImageEditField.Value = 'leftarrow';
            app.NextImageEditFieldLabel = uilabel(app.Panel_8);
            app.NextImageEditFieldLabel.Text = 'Next Image: ';
            app.NextImageEditField = uieditfield(app.Panel_8, 'text');
            app.NextImageEditField.Value = 'rightarrow';
            app.EditLineEditFieldLabel = uilabel(app.Panel_8);
            app.EditLineEditFieldLabel.Text = 'Edit Line: ';
            app.EditLineEditField = uieditfield(app.Panel_8, 'text');
            app.EditLineEditField.Value = 'w';
            app.KeyboardShortcutsLabel = uilabel(app.Panel_8);
            app.KeyboardShortcutsLabel.Text = 'Keyboard Shortcuts';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.GridLayout4 = uigridlayout(app.ViewTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Image6 = uiimage(app.GridLayout4);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image7 = uiimage(app.GridLayout4);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Label_ImageName = uilabel(app.GridLayout4);
            app.Label_ImageName.Text = '';
            app.Label_ImageRank = uilabel(app.GridLayout4);
            app.Label_ImageRank.Text = '';
            app.TableTab = uitab(app.TabGroup);
            app.TableTab.Title = 'Table';
            app.GridLayout13 = uigridlayout(app.TableTab);
            app.UITable = uitable(app.GridLayout13);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes2 = uiaxes(app.GridLayout13);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.Image8 = uiimage(app.GridLayout13);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image9 = uiimage(app.GridLayout13);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.UIAxes3 = uiaxes(app.GridLayout13);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.IDLabel = uilabel(app.GridLayout13);
            app.IDLabel.Text = 'ID';
            app.UIAxes4 = uiaxes(app.GridLayout13);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UITable2 = uitable(app.GridLayout13);
            app.UITable2.ColumnName = {''; ''};
            app.UITable2.RowName = {'CH1'; 'CH2'};
            app.UITable2.ColumnSortable = true;
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.GridLayout6 = uigridlayout(app.CreditsTab);
            app.TextArea_Citation = uitextarea(app.GridLayout6);
            app.TextArea_Citation.Editable = 'off';
            app.TextArea_Citation.Value = {'Please cite: "Automatic DNA replication tract measurement to assess replication and repair'; 'dynamics at the single molecule level"'; 'LONGJIE LI*, ARUN MOULI KOLINJIVADI*, KOKHAUR ONG, DAVID YOUNG, GABRIEL PIK LIANG MARINI,'; 'SOCK HOAI CHAN, SIAO TING CHONG, EE LING CHEW, HAODA LU, LAURENT GOLE^, WEIMIAO YU^ AND JOANNE NGEOW^'; ''; ' if you use this software.'};
            app.TextArea = uitextarea(app.GridLayout6);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'DNA Stranding Version 2.1 '; 'Software developed  by Longjie  Li  &  Laurent  Gole.  '; 'CMPL, IMCB, A*STAR, 2020  All Rights Reserved.  '; ''};
            app.Panel_2 = uipanel(app.GridLayout6);
            app.Image = uiimage(app.Panel_2);
            app.Panel_3 = uipanel(app.GridLayout6);
            app.Image2 = uiimage(app.Panel_3);
            app.Panel_4 = uipanel(app.GridLayout6);
            app.Image3 = uiimage(app.Panel_4);
            app.TextArea_2 = uitextarea(app.GridLayout6);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'contact us at lgole@imcb.a-star.edu.sg'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DNA_Stranding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end